classdef Trajectories_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        ReadImageButton              matlab.ui.control.Button
        RGBBinButton                 matlab.ui.control.Button
        DetectButton                 matlab.ui.control.Button
        OutputCSVButton              matlab.ui.control.Button
        PreviewButton                matlab.ui.control.Button
        OutputImageButton            matlab.ui.control.Button
        ButtonGroup                  matlab.ui.container.ButtonGroup
        TopleftButton                matlab.ui.control.RadioButton
        BottomLeftButton             matlab.ui.control.RadioButton
        BinarizationTreshildLabel    matlab.ui.control.Label
        BinarizationTreshildSpinner  matlab.ui.control.Spinner
        Panel                        matlab.ui.container.Panel
        ArrowWidthSpinnerLabel       matlab.ui.control.Label
        ArrowWidthSpinner            matlab.ui.control.Spinner
        DotWidthSpinnerLabel         matlab.ui.control.Label
        DotWidthSpinner              matlab.ui.control.Spinner
        ArrowColorButton             matlab.ui.control.Button
        DotColorButton               matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        UpdateButton                 matlab.ui.control.Button
        ButtonGroup_2                matlab.ui.container.ButtonGroup
        FitButton_2                  matlab.ui.control.ToggleButton
        ZoomButton                   matlab.ui.control.ToggleButton
        PanButton                    matlab.ui.control.ToggleButton
        DropDown                     matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Original_image % Original Image
        I_binary % Binary image
        Result_1 % Description
        Result_2 % Description
        Result_3 % Description
        Result_4 % Description
        trajectories_centroids % Description
        dashes_properties % Description
        groups_props_1 % Description
        cgroups_props_1 % Description
        num_groups_1 % Description
        arrow_centers_1 % Description
        len_x_1 % Description
    end
    
    methods (Access = private)
        
        function zoom_default(app)
            zoom(app.UIAxes,'off');
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UIAxes.XTickLabel = ' ';
            app.UIAxes.YTickLabel = ' ';
            app.UIAxes.XLabel.String = ' ';
            app.UIAxes.YLabel.String = ' ';
            app.UIAxes.Title.String = ' ';
        end

        % Button pushed function: ReadImageButton
        function ReadImageButtonPushed(app, event)
            [fn, fpat] = uigetfile({'*.png; *.jpg; *.bmp; *.pgm; *.jpeg', 'Image files'; '*.*', 'All files'});
            full_path = fullfile(fpat, fn);
            
            I = rgb2gray(imread(full_path));
            app.Original_image  = I;% Original Image
            imshow(I, 'Parent', app.UIAxes)
            
            app.DropDown.Items = ["Original"];
            %             app.UIAxes.Toolbar.Visible = 'on';
        end

        % Button pushed function: RGBBinButton
        function RGBBinButtonPushed(app, event)
            I = app.Original_image;
            I_open = imopen(I, strel('disk', 1));
            I_b = medfilt2(imbinarize(double(I_open), (app.BinarizationTreshildSpinner.Value / 255) * max(I_open(:))), [3, 3]);
            app.I_binary = I_b;
            app.DropDown.Items = ["Original", "Binary image"];
            imshow(I_b, 'Parent', app.UIAxes)
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            I_b = app.I_binary;
            I = app.Original_image;
            [m, n, ~] = size(I_b);
            
            props_Ib = regionprops(I_b, 'all');
            
            areas = [props_Ib.Area];
            dashes_ind = areas >= 1 * mean(areas(:));
            dots_ind = areas < 1 * mean(areas(:));
            
            dashes_img = ismember(bwlabel(I_b), find(dashes_ind));
            dots_img = ismember(bwlabel(I_b), find(dots_ind));
            
            dashes_props = regionprops(dashes_img, 'all');
            dots_props = regionprops(dots_img, 'all');
            
            app.dashes_properties = dashes_props;
            
            dots_idx_all = {dots_props.PixelIdxList};
            groups = zeros(size(I_b));
            arrow_centers = [];
            len_x_all = [];
            
            for i = 1:length(dashes_props)
                
                this_dash = ismember(bwlabel(dashes_img), i);
                groups = groups + i * this_dash;
                points = dashes_props(i).PixelList;
                X = points(:, 1); Y = points(:, 2);
                mdl = fitlm(X, Y , 'poly2');
                %     mdl = fitrsvm([ones(length(X), 1), X], Y, 'kernelFunction', 'polynomial',...
                %         'PolynomialOrder', 2);
                
                len_x = max(X) - min(X);
                len_x_all = [len_x_all, len_x]; %#ok
                x_range =  unique(min(max(min(X) - 1 * len_x:1*len_x + max(X), 1), m));
                %     x_range = [ones(length(x_range), 1), x_range];
                pred_y = min(max(round(mdl.predict(x_range')), 1), n);
                
                interp_img = zeros(size(I_b));
                for ll = 1:length(pred_y)
                    interp_img(pred_y(ll), x_range(ll)) = 1;
                end
                interp_img = bwmorph(interp_img, 'dilate', 1);
                
                %     figure, imshowpair(imdilate(dots_img, strel('disk', 3)), ...
                %         imoverlay(dashes_img, interp_img))
                
                mult_img = dots_img .* interp_img;
                
                props_mult_img = regionprops(mult_img, 'PixelIdxList');
                props_mult_img = [props_mult_img.PixelIdxList];
                
                dots_within = [];
                for this_dot = 1:length(dots_idx_all)
                    
                    dot_pixel_list = dots_idx_all{this_dot};
                    dot_within = any(ismember(props_mult_img, dot_pixel_list));


                    if dot_within
                        dots_within = [dots_within, this_dot]; %#ok
                    end
                end
                dots_within = sort(dots_within);
                if length(dots_within) > 2
                    dots_within = dots_within(1:2);
                end
                
                these_dots = ismember(bwlabel(dots_img), dots_within);
                
                
                groups = groups + i * these_dots;
                
                for dd = 1:length(dots_within)
                    dots_idx_all{dots_within(dd)} = nan;
                end
                
                this_dash_center = regionprops(this_dash,'Centroid');
                this_dash_center = [this_dash_center.Centroid];
                
                this_dot_center = regionprops(these_dots ,'Centroid');
                this_dot_center = cell2mat({this_dot_center.Centroid}');
                
                dist_1 = dist([this_dash_center; this_dot_center]');
                dist_1 = dist_1(2:end, 1);
                
                [~, ind_furthest] = max(dist_1);
                
                to_add = [this_dot_center(ind_furthest, :), this_dash_center];
                to_add_len = length(to_add);
                
                if to_add_len ~= 4
                    to_add = repmat(to_add, 1, ceil(4/to_add_len));
                    to_add = to_add(1:4) + [0, 0, 0.5, 0.5];
                end
                %     disp(length(to_add))
                
                if length(to_add) >= 4
                    arrow_centers = [arrow_centers; to_add]; %#ok
                end
            end
            % dashes_orientation = [dashes_props.Orientation];


            result_image = zeros([size(groups), 3], 'double');
            arrow_image = zeros([size(groups), 3], 'uint8');
            num_groups = max(groups(:));
            all_centroids = [];
            
            for i = 1:num_groups
                %     try
                this_group = groups == i;
                this_group_closed = imclose(this_group, strel('disk', 50));
                
                cgroup_sub = bwmorph(and(~this_group, this_group_closed), 'open', 3);
                
                %     figure, imshow(cgroup_sub), figure, imshow(this_group)
                
                cgroup_box = bwmorph(bwmorph(this_group_closed, 'dilate', 1), 'remove');
                
                groups_props = regionprops(this_group);
                
                % This handles boader objects,that have no dots
                if any(cgroup_sub(:))
                    cgroups_props = regionprops(cgroup_sub);
                else
                    cgroups_props = regionprops(this_group);
                end
                
                
                result_image = double(result_image) + double(this_group * 255);
                %     arrow_image = double(arrow_image) + double(this_group * 255);
                
                result_image = imoverlay(result_image, cgroup_box, 'r');
                
                result_image = insertMarker(result_image, cell2mat({groups_props.Centroid}'), 'x', ...
                    'size', app.DotWidthSpinner.Value, 'color', app.Label_2.BackgroundColor*255);
                
                result_image = insertMarker(result_image, cell2mat({cgroups_props.Centroid}'), ...
                    'size', app.DotWidthSpinner.Value, 'color', app.Label_2.BackgroundColor*255);
                
                arrow_image = draw_arrow_fcn(arrow_image, arrow_centers(i, :), len_x, app.ArrowWidthSpinner.Value);
                wid = app.ArrowWidthSpinner.Value;
                if wid >= 3
                    
                    arrow_image = imopen(imdilate(imfill(arrow_image, 'holes'), strel('disk', wid)), strel('disk', wid));
                
                end
                to_add_2 = [[groups_props.Centroid], [cgroups_props.Centroid]];
                
                to_add_2_len = length(to_add_2);
                
                if to_add_2_len ~= 10
                    to_add_2 = repmat(to_add_2, 1, ceil(10/length(to_add_2)));
                    to_add_2 = to_add_2(1:10);
                end
                all_centroids = [all_centroids; to_add_2]; %#ok
            end
            
            imshow(result_image, 'Parent', app.UIAxes)
            zoom_default(app)
            msgbox(sprintf('Detected %s trajectories', num2str(length(dashes_props))), 'Detection Finished')
            app.DropDown.Items = ["Original", "Binary image", "Result 1", "Result 2", "Result 3", "Result 4"];
            
            
            app.Result_1 = result_image;
            app.Result_2 = imoverlay(I, arrow_image(:, :, 1) > 0.5, app.Label.BackgroundColor);
            app.Result_3 = imoverlay(I_b, arrow_image(:, :, 1) > 0.5, app.Label.BackgroundColor);
            app.Result_4 = imoverlay(zeros(size(arrow_image)) + 255, arrow_image(:, :, 1) > 0.5, app.Label.BackgroundColor);
            
            app.trajectories_centroids = all_centroids;
            
            app.groups_props_1 = groups_props;
            app.cgroups_props_1 = cgroups_props;
            app.num_groups_1 = num_groups;
            app.arrow_centers_1 = arrow_centers;
            app.len_x_1 = len_x_all;
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            
            switch selectedButton.Text
                case 'Fit'
                    zoom_default(app);
                    
                case 'Zoom'
                    zoom(app.UIAxes,'on');
                    
                case 'Pan'
                    pan(app.UIAxes,'on');
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            % disp(app.DropDown.Items(app.DropDown.Value))
            switch app.DropDown.Value
                case "Original"
                    imshow(app.Original_image, 'Parent', app.UIAxes)
                    %                     zoom_default(app)
                case "Binary image"
                    imshow(app.I_binary, 'Parent', app.UIAxes)
                    %                     zoom_default(app)
                case "Result 1"
                    imshow(app.Result_1, 'Parent', app.UIAxes)
                    %                     zoom_default(app)
                case "Result 2"
                    imshow(app.Result_2, 'Parent', app.UIAxes)
                    %                     zoom_default(app)
                case "Result 3"
                    imshow(app.Result_3, 'Parent', app.UIAxes)
                    %                     zoom_default(app)
                case "Result 4"
                    imshow(app.Result_4, 'Parent', app.UIAxes)
                    %                     zoom_default(app)
            end
        end

        % Button pushed function: OutputImageButton
        function OutputImageButtonPushed(app, event)
            fpath = uigetdir('Select output directory');
            imwrite(app.Result_1, fullfile(fpath, 'Result1.png'), 'png')
            imwrite(app.Result_2, fullfile(fpath, 'Result2.png'), 'png')
            imwrite(app.Result_3, fullfile(fpath, 'Result3.png'), 'png')
            imwrite(app.Result_4, fullfile(fpath, 'Result4.png'), 'png')
        end

        % Button pushed function: OutputCSVButton
        function OutputCSVButtonPushed(app, event)
            
            
            a = app.trajectories_centroids;
            len = size(a, 1);
            
            a = [[1:len]', a, ones(len, 1)];%#ok
            a = [zeros(1, 12); a];
            %%
            text_img = app.Original_image;
            
            dashes_props = app.dashes_properties;
            
            for i = 1:len
                text_img = insertText(text_img, [dashes_props(i).Centroid(1), ...
                    dashes_props(i).Centroid(2)] - 10, num2str(i), 'FontSize', 10);
            end
            
            
            pathh = uigetdir();
            
            imwrite(text_img, fullfile(pathh,  'Numbering index.jpg'), 'jpg');
            col_names = ["Index", "xg1", "yg2", "xg2", "yg2", "xg3", "yg3", "xg4",...
                "yg4", "xg5", "yg5", "Origin Type"];
            
            %             disp(app.BottomLeftButton.Value)
            
            [~, n, ~] = size(app.Original_image);
            if app.BottomLeftButton.Value == 1
                T = [0, 0, n, 0, n, 0, n, 0, n, 0, n, -1];
                a = abs(T - a);
            end
            
            xlswrite(fullfile(pathh, 'Trajectories Centroids.xlsx'), a);
            xlswrite(fullfile(pathh, 'Trajectories Centroids.xlsx'), col_names);
            
        end

        % Button pushed function: ArrowColorButton
        function ArrowColorButtonPushed(app, event)
            app.Label.BackgroundColor = uisetcolor();
        end

        % Button pushed function: DotColorButton
        function DotColorButtonPushed(app, event)
            app.Label_2.BackgroundColor = uisetcolor();
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            result_image = im2uint8(app.I_binary);
            arrow_image = zeros([size(app.I_binary), 3], 'uint8');
            
            for i = 1:app.num_groups_1
                result_image = insertMarker(result_image, cell2mat({app.groups_props_1.Centroid}'), 'x', ...
                    'size', app.DotWidthSpinner.Value, 'color', app.Label_2.BackgroundColor*255);
                
                result_image = insertMarker(result_image, cell2mat({app.cgroups_props_1.Centroid}'), ...
                    'size', app.DotWidthSpinner.Value, 'color', app.Label_2.BackgroundColor*255);
                
                arrow_image = draw_arrow_fcn(arrow_image, app.arrow_centers_1(i, :), app.len_x_1(i), app.ArrowWidthSpinner.Value);
                arrow_image = imopen(imdilate(imfill(arrow_image, 'holes'), strel('disk', 2)), strel('disk', 2));
            end
            
            imshow(result_image, 'Parent', app.UIAxes)
            zoom_default(app)            
            
            app.Result_1 = result_image;
            app.Result_2 = imoverlay(app.Original_image, arrow_image(:, :, 1) > 0.5, app.Label.BackgroundColor);
            app.Result_3 = imoverlay(app.I_binary, arrow_image(:, :, 1) > 0.5, app.Label.BackgroundColor);
            app.Result_4 = imoverlay(zeros(size(arrow_image)) + 255, arrow_image(:, :, 1) > 0.5, app.Label.BackgroundColor);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ReadImageButton = uibutton(app.UIFigure, 'push');
            app.ReadImageButton.ButtonPushedFcn = createCallbackFcn(app, @ReadImageButtonPushed, true);
            app.ReadImageButton.Text = 'Read Image';
            app.RGBBinButton = uibutton(app.UIFigure, 'push');
            app.RGBBinButton.ButtonPushedFcn = createCallbackFcn(app, @RGBBinButtonPushed, true);
            app.RGBBinButton.Text = 'RGB / Bin';
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.OutputCSVButton = uibutton(app.UIFigure, 'push');
            app.OutputCSVButton.ButtonPushedFcn = createCallbackFcn(app, @OutputCSVButtonPushed, true);
            app.OutputCSVButton.Text = 'Output CSV';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview ';
            app.OutputImageButton = uibutton(app.UIFigure, 'push');
            app.OutputImageButton.ButtonPushedFcn = createCallbackFcn(app, @OutputImageButtonPushed, true);
            app.OutputImageButton.Text = {'Output '; 'Image'};
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.TopleftButton = uiradiobutton(app.ButtonGroup);
            app.TopleftButton.Text = 'Top left';
            app.TopleftButton.Value = true;
            app.BottomLeftButton = uiradiobutton(app.ButtonGroup);
            app.BottomLeftButton.Text = 'Bottom Left';
            app.BinarizationTreshildLabel = uilabel(app.UIFigure);
            app.BinarizationTreshildLabel.Text = {'Binarization'; ' Treshild'};
            app.BinarizationTreshildSpinner = uispinner(app.UIFigure);
            app.BinarizationTreshildSpinner.Limits = [1 255];
            app.BinarizationTreshildSpinner.Value = 150;
            app.Panel = uipanel(app.UIFigure);
            app.ArrowWidthSpinnerLabel = uilabel(app.Panel);
            app.ArrowWidthSpinnerLabel.Text = 'Arrow Width';
            app.ArrowWidthSpinner = uispinner(app.Panel);
            app.ArrowWidthSpinner.Limits = [1 10];
            app.ArrowWidthSpinner.Value = 1;
            app.DotWidthSpinnerLabel = uilabel(app.Panel);
            app.DotWidthSpinnerLabel.Text = 'Dot Width';
            app.DotWidthSpinner = uispinner(app.Panel);
            app.DotWidthSpinner.Limits = [1 10];
            app.DotWidthSpinner.Value = 1;
            app.ArrowColorButton = uibutton(app.Panel, 'push');
            app.ArrowColorButton.ButtonPushedFcn = createCallbackFcn(app, @ArrowColorButtonPushed, true);
            app.ArrowColorButton.Text = 'Arrow Color';
            app.DotColorButton = uibutton(app.Panel, 'push');
            app.DotColorButton.ButtonPushedFcn = createCallbackFcn(app, @DotColorButtonPushed, true);
            app.DotColorButton.Text = 'Dot Color';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '';
            app.UpdateButton = uibutton(app.Panel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.FitButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.FitButton_2.Text = 'Fit';
            app.FitButton_2.Value = true;
            app.ZoomButton = uitogglebutton(app.ButtonGroup_2);
            app.ZoomButton.Text = 'Zoom';
            app.PanButton = uitogglebutton(app.ButtonGroup_2);
            app.PanButton.Text = 'Pan';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Trajectories_UI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end